classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Label_4                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        ImageResourceEditFieldLabel    matlab.ui.control.Label
        ImageResourceEditField         matlab.ui.control.EditField
        Label_2                        matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        Label_3                        matlab.ui.control.Label
        Slider_3                       matlab.ui.control.Slider
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        Label_7                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Spinner                        matlab.ui.control.Spinner
        Label_9                        matlab.ui.control.Label
        Spinner_2                      matlab.ui.control.Spinner
        view1Button                    matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        Label_10                       matlab.ui.control.Label
        ImageResourceEditField_2Label  matlab.ui.control.Label
        ImageResourceEditField_2       matlab.ui.control.EditField
        view2Button                    matlab.ui.control.Button
        Label_11                       matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        view2Label                     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function viewButtonPushed(app, event)
            
        end

        % Value changed function: ImageResourceEditField
        function ImageResourceEditFieldValueChanged(app, event)
            value = app.ImageResourceEditField.Value;
            
        end

        % Button pushed function: view1Button
        function view1ButtonPushed(app, event)
k=app.Slider.Value;           %导热系数,控制平滑
lambda=app.Slider_3.Value;    %控制平滑
N=app.Slider_2.Value;           %迭代次数

img=double(imread(app.ImageResourceEditField.Value));
imshow(img,[]);
[m,n]=size(img);

imgn=zeros(m,n);
for i=1:N

    for p=2:m-1
        for q=2:n-1
            %当前像素的散度，对四个方向分别求偏导，局部不同方向上的变化量，
            %如果变化较多，就证明是边界，想方法保留边界
            NI=img(p-1,q)-img(p,q);
            SI=img(p+1,q)-img(p,q);
            EI=img(p,q-1)-img(p,q);
            WI=img(p,q+1)-img(p,q);
            
            %四个方向上的导热系数，该方向变化越大，求得的值越小，从而达到保留边界的目的
            cN=exp(-NI^2/(k*k));
            cS=exp(-SI^2/(k*k));
            cE=exp(-EI^2/(k*k));
            cW=exp(-WI^2/(k*k));
            
            imgn(p,q)=img(p,q)+lambda*(cN*NI+cS*SI+cE*EI+cW*WI);  %扩散后的新值      
        end
    end
    
    img=imgn;       %整个图像扩散完毕，用已扩散图像的重新扩散。
end
figure;
img2=mat2gray(imgn);%img2是滤波后的图像
imshow(img2);
%imwrite(img2,'MRimage\9900.png');

T=graythresh(img2);
img3=imbinarize(img2,T);%Otus进行分割
%se=strel('octagon',6);se2=strel('octagon',27);
se=strel(app.ListBox.Value,app.Spinner.Value);se2=strel(app.ListBox.Value,app.Spinner_2.Value);
img4=imopen(img3,se);
img5=imclose(img4,se2);
J=immultiply(img2,img5);
imshow(J);
        end

        % Button pushed function: view2Button
        function view2ButtonPushed(app, event)
            origin=imread(app.ImageResourceEditField_2.Value);

T2=graythresh(origin);
I=imbinarize(origin,T2);%Otus进行分割
figure 
imshow(I);
[M,N]=size(I);
[y,x]=getpts; %单击取点后，按enter结束
x1=round(x);
y1=round(y);
seed=I(x1,y1); %获取中心像素灰度值

J=zeros(M,N);
J(x1,y1)=1;

count=1; %待处理点个数
threshold=0.15;
while count>0
    count=0;
    for i=1:M %遍历整幅图像
    for j=1:N
        if J(i,j)==1 %点在“栈”内
        if (i-1)>1&&(i+1)<M&&(j-1)>1&&(j+1)<N %3*3邻域在图像范围内
            for u=-1:1 %8-邻域生长
            for v=-1:1
                if J(i+u,j+v)==0&&abs(I(i+u,j+v)-seed)<=threshold
                    J(i+u,j+v)=1;
                    count=count+1;  %记录此次新生长的点个数
                end
            end
            end
        end
        end
    end
    end
end
subplot(2,2,1),imshow(I);
title("二值化")

subplot(2,2,2),imshow(J);
title("区域生长")

se2=strel('disk',24);%闭操作
J=imclose(J,se2);%闭操作

subplot(2,2,3),imshow(J);
title("闭操作")

origin=double(origin)/255;

result=immultiply(origin,J);%与原图像相乘
subplot(2,2,4),imshow(result);
title("结果")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {332, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '形态学方法';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '导热系数';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [0 20];
            app.Slider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.Slider.Value = 6;
            app.ImageResourceEditFieldLabel = uilabel(app.LeftPanel);
            app.ImageResourceEditFieldLabel.Text = 'Image Resource';
            app.ImageResourceEditField = uieditfield(app.LeftPanel, 'text');
            app.ImageResourceEditField.ValueChangedFcn = createCallbackFcn(app, @ImageResourceEditFieldValueChanged, true);
            app.ImageResourceEditField.Value = 'MRimage\\T1\\830.png';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '迭代次数';
            app.Slider_2 = uislider(app.LeftPanel);
            app.Slider_2.Limits = [0 40];
            app.Slider_2.Value = 15;
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '平滑控制';
            app.Slider_3 = uislider(app.LeftPanel);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.Value = 0.2;
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '各向异性扩散滤波';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '结构元素形状';
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.Items = {'disk', 'octagon', ''};
            app.ListBox.Value = 'disk';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '形态学分割颅骨';
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = '开操作';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Limits = [0 10];
            app.Spinner.Value = 6;
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '闭操作';
            app.Spinner_2 = uispinner(app.LeftPanel);
            app.Spinner_2.Limits = [12 40];
            app.Spinner_2.Value = 24;
            app.view1Button = uibutton(app.LeftPanel, 'push');
            app.view1Button.ButtonPushedFcn = createCallbackFcn(app, @view1ButtonPushed, true);
            app.view1Button.Text = 'view1';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label_10 = uilabel(app.RightPanel);
            app.Label_10.Text = '区域生长方法';
            app.ImageResourceEditField_2Label = uilabel(app.RightPanel);
            app.ImageResourceEditField_2Label.Text = 'Image Resource';
            app.ImageResourceEditField_2 = uieditfield(app.RightPanel, 'text');
            app.ImageResourceEditField_2.Value = 'MRimage\\T1\\830.png';
            app.view2Button = uibutton(app.RightPanel, 'push');
            app.view2Button.ButtonPushedFcn = createCallbackFcn(app, @view2ButtonPushed, true);
            app.view2Button.Text = 'view2';
            app.Label_11 = uilabel(app.RightPanel);
            app.Label_11.Text = '请在显示的第一个二值化图像中单击选择生长点';
            app.Label_12 = uilabel(app.RightPanel);
            app.Label_12.Text = '然后点击回车';
            app.view2Label = uilabel(app.RightPanel);
            app.view2Label.Text = '单击view2后';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end